classdef Gauss < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        nmEditFieldLabel    matlab.ui.control.Label
        nmEditField         matlab.ui.control.NumericEditField
        mmEditFieldLabel    matlab.ui.control.Label
        mmEditField         matlab.ui.control.NumericEditField
        UIAxes              matlab.ui.control.UIAxes
        Button              matlab.ui.control.Button
        CCDButton           matlab.ui.control.Button
        mradEditFieldLabel  matlab.ui.control.Label
        mradEditField       matlab.ui.control.NumericEditField
        zmmLabel            matlab.ui.control.Label
        zmmEditField        matlab.ui.control.NumericEditField
        Button_2            matlab.ui.control.Button
    end

    
    properties (Access = private)


    end
    
    methods (Access = private)
        
        function p = LSM(~,x,y,z)
            % 最小二乘法
            [a, b] = size(x);
            Para_1_1 = sum(sum(x.*x));
            Para_1_2 = sum(sum(x.*y));
            Para_1_3 = sum(sum(x));
            Para_2_1 = Para_1_2;
            Para_2_2 = sum(sum(y.*y));
            Para_2_3 = sum(sum(y));
            Para_3_1 = Para_1_3;
            Para_3_2 = Para_2_3;
            Para_3_3 = a*b;
            Para = [Para_1_1,Para_1_2, Para_1_3;Para_2_1, Para_2_2, Para_2_3;Para_3_1, Para_3_2, Para_3_3];
            
            b1 = sum(sum(x.*z));
            b2 = sum(sum(y.*z));
            b3 = sum(sum(z));
            p = Para\[b1;b2;b3];                
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global lambda w_0 k z_r w R_z phi_z A Phi  z0 a b
            lambda  = app.nmEditField.Value*1e-9;
            w_0     = app.mmEditField.Value*1e-3;
            
            z0      = app.zmmEditField.Value*1e-3;
            
            k       = 2*pi/lambda;
            z_r     = pi*1*w_0^2/lambda;
        
            w       = @(z) w_0*sqrt(1+(z/z_r).^2);%高斯光的光斑半径; % Description
            R_z     = @(z) z./2./(z.^2+z_r^2);
            phi_z   = @(z) atan(z./z_r);
            A       = @(x,y,z) 1./w(z).*exp((-x.^2-y.^2)./w(z).^2);
            Phi     = @(x,y,z) k*((x.^2+y.^2).*R_z(z)+z)-phi_z(z);
            
            z       = -1:1e-2:1;
            theta   = sqrt(lambda/pi/z_r);% 远场发射角
            z1      = theta*z;
            plot(app.UIAxes,z,w(z),'k',z,-w(z),'k',z,z1,'k--',z,-z1,'k--',z,0*z,'k--')%光斑半径随z轴的变化
            
            app.UIAxes.Title.String = '光束半径在z轴的变化';
            
            
        end

        % Value changed function: nmEditField
        function nmEditFieldValueChanged(app, event)
            value = app.nmEditField.Value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global   A Phi a b z0  
            
            jiaodu = app.mradEditField.Value*1e-3;
            x = linspace(-6.40e-3,6.40e-3,640);
            y = linspace(-5.12e-3,5.12e-3,512);
            [X, Y] = meshgrid(x, y);
            [n,h] = size(X);
            theta   = -jiaodu:1e-4:jiaodu;
            
%             phi = Phi(X, Y, z0*ones(n,h));%正入射时z=z0是的相位分布
            
            for q = 1:length(theta)
                alpha = theta(q);
                beta = 0e-3;
                E_L = A(X,Y,z0*ones(n,h)).*exp(1i*Phi(X,Y,z0*ones(n,h)));% 正入射在探测面的电场分布
                Ralpha = [cos(alpha),0,sin(alpha);0,1,0;-sin(alpha),0,cos(alpha)];%绕y轴转动
                Rbeta = [1,0,0;0,cos(beta),sin(beta);0,-sin(beta),cos(beta)];%绕x轴转动
                for m = 1:n
                    for j = 1:h
                        R = [X(m,j) Y(m,j) z0]*Ralpha*Rbeta;
                        X1(m,j) = R(1);Y1(m,j) = R(2);Z1(m,j) = R(3);
                    end
                end
                E_R = A(X1,Y1,Z1).*exp(1i*Phi(X1,Y1,Z1));%倾斜入射在探测面的电场分布
                E_Phi = Phi(X,Y,z0*ones(n,h)) - Phi(X1,Y1,Z1);
                p = app.LSM(X,Y,E_Phi);
                a(q) = p(1);
                b(q) = p(2);
            end
%             a1 = a/(-k);
%             plot(theta*1e3,a1*1e3,'LineWidth',2)
%             % plot(theta1*1e3,a1)
%             xticks([-jiaodu*1e3  0  jiaodu*1e3])
%             yticks([-jiaodu*1e3  0 jiaodu*1e3])
%             xlim([-jiaodu*1e3,jiaodu*1e3])
%             ylim([-jiaodu*1e3,jiaodu*1e3])
%             xlabel("偏转角度/mrad")
%             ylabel('测量值/mrad')
%             grid on
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global a k ;
            jiaodu = app.mradEditField.Value*1e-3;
            theta = -jiaodu:1e-4:jiaodu;
            plot(theta*1e3, a/(-k)*1e3, 'LineWidth',2)
            xticks([-jiaodu*1e3  0  jiaodu*1e3])
            yticks([-jiaodu*1e3  0 jiaodu*1e3])
            xlabel("偏转角度/mrad")
            ylabel('测量值/mrad')
            ylim([-jiaodu*1e3, jiaodu*1e3 ])
            grid on
        end

        % Button pushed function: CCDButton
        function CCDButtonPushed(app, event)
            global A Phi z0  paCCD pbCCD
            jiaodu  = app.mradEditField.Value*1e-3;
            theta   = -jiaodu:1e-4:jiaodu;
            x       = -6.40e-3:2e-5:6.40e-3;
            y       = linspace(-5.12e-3,5.12e-3,512);
            [X, Y]  = meshgrid(x, y);
            [n,h]   = size(X);
            AL      = @(x,y) A(x,y,z0*ones(n,h)).*A(x,y,z0*ones(n,h));
            for q = 1:length(theta)
                alpha = theta(q);
                for m = 1:n
                    for j = 1:h
                        
                        
                    end
                end
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.nmEditFieldLabel = uilabel(app.UIFigure);
            app.nmEditFieldLabel.Text = '波长/nm';
            app.nmEditField = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField.ValueChangedFcn = createCallbackFcn(app, @nmEditFieldValueChanged, true);
            app.nmEditField.Value = 1064;
            app.mmEditFieldLabel = uilabel(app.UIFigure);
            app.mmEditFieldLabel.Text = '光斑半径/mm';
            app.mmEditField = uieditfield(app.UIFigure, 'numeric');
            app.mmEditField.Value = 0.3125;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = 0;
            app.UIAxes.YTick = 0;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '计算平面的相位分布';
            app.CCDButton = uibutton(app.UIFigure, 'push');
            app.CCDButton.ButtonPushedFcn = createCallbackFcn(app, @CCDButtonPushed, true);
            app.CCDButton.Text = '计算CCD的相位分布';
            app.mradEditFieldLabel = uilabel(app.UIFigure);
            app.mradEditFieldLabel.Text = '偏转角度/mrad';
            app.mradEditField = uieditfield(app.UIFigure, 'numeric');
            app.mradEditField.Limits = [1 100];
            app.mradEditField.Value = 10;
            app.zmmLabel = uilabel(app.UIFigure);
            app.zmmLabel.Text = 'z轴距离/mm';
            app.zmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '平面的偏角计算';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gauss

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end